classdef Virtual_Robot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Theta1EditFieldLabel      matlab.ui.control.Label
        Theta1EditField           matlab.ui.control.NumericEditField
        Theta2EditFieldLabel      matlab.ui.control.Label
        Theta2EditField           matlab.ui.control.NumericEditField
        Theta3EditFieldLabel      matlab.ui.control.Label
        Theta3EditField           matlab.ui.control.NumericEditField
        ForwardButton             matlab.ui.control.Button
        AzimuthSliderLabel        matlab.ui.control.Label
        AzimuthSlider             matlab.ui.control.Slider
        ElevationSliderLabel      matlab.ui.control.Label
        ElevationSlider           matlab.ui.control.Slider
        ShowCheckerboardCheckBox  matlab.ui.control.CheckBox
        ShowObjectsCheckBox       matlab.ui.control.CheckBox
        ShowCalibToolCheckBox     matlab.ui.control.CheckBox
        ShowGripperCheckBox       matlab.ui.control.CheckBox
        PixelXEditFieldLabel      matlab.ui.control.Label
        PixelXEditField           matlab.ui.control.NumericEditField
        PixelYEditFieldLabel      matlab.ui.control.Label
        PixelYEditField           matlab.ui.control.NumericEditField
        ViewListBoxLabel          matlab.ui.control.Label
        ViewListBox               matlab.ui.control.ListBox
        SnapCameraButton          matlab.ui.control.Button
        NotinworkspaceLabel       matlab.ui.control.Label
        NotinworkspaceLamp        matlab.ui.control.Lamp
        JointAnglesLabel          matlab.ui.control.Label
        XEditFieldLabel           matlab.ui.control.Label
        CartesianXEditField       matlab.ui.control.NumericEditField
        YEditFieldLabel           matlab.ui.control.Label
        CartesianYEditField       matlab.ui.control.NumericEditField
        ZEditFieldLabel           matlab.ui.control.Label
        CartesianZEditField       matlab.ui.control.NumericEditField
        EndeffectorCartesianCoordinateLabel  matlab.ui.control.Label
        InverseButton             matlab.ui.control.Button
        ApproachObjectButton      matlab.ui.control.Button
        GrabCubeButton            matlab.ui.control.Button
        GrabCylinderButton        matlab.ui.control.Button
        CubeGrabbedCheckBox       matlab.ui.control.CheckBox
        CylinderGrabbedCheckBox   matlab.ui.control.CheckBox
        FwdTrajectoryButton       matlab.ui.control.Button
        InvTrajectoryButton       matlab.ui.control.Button
        Button                    matlab.ui.control.Button
        Button_2                  matlab.ui.control.Button
        Button_3                  matlab.ui.control.Button
        Button_4                  matlab.ui.control.Button
        Button_5                  matlab.ui.control.Button
        Button_6                  matlab.ui.control.Button
        Button_7                  matlab.ui.control.Button
        Button_8                  matlab.ui.control.Button
        Button_9                  matlab.ui.control.Button
        Button_10                 matlab.ui.control.Button
        Button_11                 matlab.ui.control.Button
        Button_12                 matlab.ui.control.Button
        ControlPanelLabel         matlab.ui.control.Label
        ApproachCubeButton        matlab.ui.control.Button
        ApproachCylinderButton    matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Theta1EditField
        function Theta1EditFieldValueChanged(app, event)
            
            %%%%%%%%%%%%%%%%%%%%
            % Do NOT edit this %
            %%%%%%%%%%%%%%%%%%%%
            
            value = app.Theta1EditField.Value;
            
        end

        % Value changed function: Theta2EditField
        function Theta2EditFieldValueChanged(app, event)
           
            %%%%%%%%%%%%%%%%%%%%
            % Do NOT edit this %
            %%%%%%%%%%%%%%%%%%%%
            
            value = app.Theta2EditField.Value;
            
        end

        % Value changed function: Theta3EditField
        function Theta3EditFieldValueChanged(app, event)
            
            %%%%%%%%%%%%%%%%%%%%
            % Do NOT edit this %
            %%%%%%%%%%%%%%%%%%%%            
            
            value = app.Theta3EditField.Value;
            
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % This is the MAIN function.                      %
            % All functions will finally call this function   %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Settings - Do NOT edit, EXCEPT if you    %
            % % need to declare some of them as global %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            clc
            app.NotinworkspaceLamp.Enable = 'off'; % turn off warning lamp
            
            L1 = 0;   % mm, Robot link lengths
            L2 = 150; % mm
            L3 = 100; % mm
            Le = 50;  % mm, end-effector, assume L2 = L3 + Le so that calculation of workspace easier
            
            thetaxRobotWorld = 180.1;           % Transformation between robot and world frames
            thetayRobotWorld = 0.1;             % thetax needs to be around 180 because world frame is flipped
            thetazRobotWorld = 1;               % thetay and thetaz small so that world and robot somewhat aligned apart     
            PRobotWorld = [-100;200;-20];       % from being upside down.
            
            RzWorldObjectBox = 20;              % Transformation between world and box (cube)
                                                % Rotation should only be about z-axis because during calibration, 
                                                % we put checkerboard on object.    
                                                % This is not an assumption but truth.
            PWorldObjectBox = [-50;100;0];      % Box frame wrt. world frame (fixed)
                                                % z-value should be zero because we assumed
                                                % checkerboard (world frame) was on top of
                                                % object.
            PWorldObjectCylinder = [100;50;0];  % Cylinder frame wrt. world frame (fixed)
                                                % z-value should be zero because we assumed
                                                % checkerboard (world frame) was on top of
                                                % object.
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Settings - Leave them as they are at the moment, % 
            % but CHANGE the values AFTER you have done the    %
            % camera & robot calibration workshop              %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            checkerboardScale = 22; % size of checkerboard box in mm - change this
            
            % Camera intrinsics i.e. relationship between pixel frame and camera-centred frame - changed version
            CameraIntrinsics = [9.680301351561671e+02,0,0;
                0,9.681244230787817e+02,0;
                6.374537532574340e+02,3.341370702892024e+02,1];
            
            % Camera extrinsics (rotation) - changed version
            RCameraWorld = [0.999952630910421,-0.009665852476411,-0.001143342132380; 
                0.009676606842265,0.999905082543870,0.009807608421833;
                0.001048434713101,-0.009818207516653,0.999951250604655];
            
            % Camera intrinsics (translation) - changed version
            PCameraWorld = [-33.311967791009540,-42.571184101341650,3.485812262572603e+02];  

            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Forward Kinematics - CHANGE the  % 
            % matrices T01, T12, T23, T02, T03 %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %global variables to record the Theta 1-3 intial values for FwdTrajectory use
            global theta1_0;
            global theta2_0;
            global theta3_0;            
            theta1_0 = app.Theta1EditField.Value;
            theta2_0 = app.Theta2EditField.Value;
            theta3_0 = app.Theta3EditField.Value;
            
            q1 = app.Theta1EditField.Value*pi/180; % Transfer degree to radian
            q2 = app.Theta2EditField.Value*pi/180; % Transfer degree to radian
            q3 = app.Theta3EditField.Value*pi/180; % Transfer degree to radian
            
            T01 = [cos(q1),-sin(q1),0,0;sin(q1),cos(q1),0,0;0,0,1,0;0,0,0,1]; % Transformation matrix from frame 0 to 1
            
            T12 = [cos(q2),-sin(q2),0,0;0,0,-1,0;sin(q2),cos(q2),0,0;0,0,0,1]; % Transformation matrix from frame 1 to 2
             
            T23 = [cos(q3),-sin(q3),0,L2;sin(q3),cos(q3),0,0;0,0,1,0;0,0,0,1]; % Transformation matrix from frame 2 to 3
            
            T02 = T01*T12; % Compound transformation from frame 0 to 2
            
            T03 = T02*T23; % Compound transformation from frame 0 to 3
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Forward Kinematics - Do NOT edit %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            P33 = [L3+Le;0;0;1]; % End effector wrt frame 3
            P03 = T03*P33;  % End effector wrt frame 0
            
            x2 = T02(1,4);  % Position of frame 2
            y2 = T02(2,4);
            z2 = T02(3,4);
            
            x3 = T03(1,4);  % Position of frame 3
            y3 = T03(2,4);
            z3 = T03(3,4);
            
            xp = P03(1);    % Position of end-effector
            yp = P03(2);
            zp = P03(3);
            
            app.CartesianXEditField.Value = xp;  % Display X coordinate
            app.CartesianYEditField.Value = yp;  % Display Y coordinate
            app.CartesianZEditField.Value = zp;  % Display Z coordinate
            
            % global variables to record XYZ intial values for InvTrajectory Button use
            global x_0;
            global y_0;
            global z_0;
            x_0 = xp;
            y_0 = yp;
            z_0 = zp;
            
            % grab cube func
            if app.CubeGrabbedCheckBox.Value == true
                PWorldObjectBox = [0.9998*(xp+100)-0.0175*(yp-200)+0.0017*(zp+20);
                    -0.0175*(xp+100)-0.9998*(yp-200)-0.0017*(zp+20);
                    0.0017*(xp+100)+0.0018*(yp-200)-(zp+20)];
            else
                PWorldObjectBox = [-50; 100; 0];
            end
            
            % grab cylinder func
            if app.CylinderGrabbedCheckBox.Value == true
                PWorldObjectCylinder = [0.9998*(xp+100)-0.0175*(yp-200)+0.0017*(zp+20);
                    -0.0175*(xp+100)-0.9998*(yp-200)-0.0017*(zp+20);
                    0.0017*(xp+100)+0.0018*(yp-200)-(zp+20)];
            else
                PWorldObjectCylinder = [100; 50; 0];
            end
                        
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Physical dimensions of link 2 - Do NOT edit %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            link2Corners = [0 -25 -25 1; % The 1 in last column is just for homogeneous coordinates
                0 25 -25 1;
                0 25 25 1;
                0 -25 25 1;
                L2 -25 -25 1;
                L2 25 -25 1;
                L2 25 25 1;
                L2 -25 25 1];
            
            link2CornersTransformed = T02*link2Corners';
            link2CornersTransformed =  link2CornersTransformed';
            
            Z2 = [ones(1,5)*mean(link2CornersTransformed(1:4,3));
                link2CornersTransformed(1:4,3)' link2CornersTransformed(1,3);
                link2CornersTransformed(5:8,3)' link2CornersTransformed(5,3);
                ones(1,5)*mean(link2CornersTransformed(5:8,3))];
            X2 = [ones(1,5)*mean(link2CornersTransformed(1:4,1));
                link2CornersTransformed(1:4,1)' link2CornersTransformed(1,1);
                link2CornersTransformed(5:8,1)' link2CornersTransformed(5,1);
                ones(1,5)*mean(link2CornersTransformed(5:8,1))];
            Y2 = [ones(1,5)*mean(link2CornersTransformed(1:4,2));
                link2CornersTransformed(1:4,2)' link2CornersTransformed(1,2);
                link2CornersTransformed(5:8,2)' link2CornersTransformed(5,2);
                ones(1,5)*mean(link2CornersTransformed(5:8,2))];
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Physical dimensions of link 3 - Do NOT edit %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            link3Corners = [0 -20 -20 1; % The 1 in last column is just for homogeneous coordinates
                0 20 -20 1;
                0 20 20 1;
                0 -20 20 1;
                L3 -20 -20 1;
                L3 20 -20 1;
                L3 20 20 1;
                L3 -20 20 1];
            
            link3CornersTransformed = T03*link3Corners';
            link3CornersTransformed =  link3CornersTransformed';
            
            Z3 = [ones(1,5)*mean(link3CornersTransformed(1:4,3));
                link3CornersTransformed(1:4,3)' link3CornersTransformed(1,3);
                link3CornersTransformed(5:8,3)' link3CornersTransformed(5,3);
                ones(1,5)*mean(link3CornersTransformed(5:8,3))];
            X3 = [ones(1,5)*mean(link3CornersTransformed(1:4,1));
                link3CornersTransformed(1:4,1)' link3CornersTransformed(1,1);
                link3CornersTransformed(5:8,1)' link3CornersTransformed(5,1);
                ones(1,5)*mean(link3CornersTransformed(5:8,1))];
            Y3 = [ones(1,5)*mean(link3CornersTransformed(1:4,2));
                link3CornersTransformed(1:4,2)' link3CornersTransformed(1,2);
                link3CornersTransformed(5:8,2)' link3CornersTransformed(5,2);
                ones(1,5)*mean(link3CornersTransformed(5:8,2))];
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Physical dimensions of left gripper - Do NOT edit %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            gripperLCorners = [L3 -20 -25 1; % The 1 in last column is just for homogeneous coordinates
                L3 20 -25 1;
                L3 20 -18 1;
                L3 -20 -18 1;
                L3+1.5*Le -20 -25 1;
                L3+1.5*Le 20 -25 1;
                L3+1.5*Le 20 -18 1;
                L3+1.5*Le -20 -18 1];
            
            gripperLCornersTransformed = T03*gripperLCorners';
            gripperLCornersTransformed =  gripperLCornersTransformed';
            
            Zgl = [ones(1,5)*mean(gripperLCornersTransformed(1:4,3));
                gripperLCornersTransformed(1:4,3)' gripperLCornersTransformed(1,3);
                gripperLCornersTransformed(5:8,3)' gripperLCornersTransformed(5,3);
                ones(1,5)*mean(gripperLCornersTransformed(5:8,3))];
            Xgl = [ones(1,5)*mean(gripperLCornersTransformed(1:4,1));
                gripperLCornersTransformed(1:4,1)' gripperLCornersTransformed(1,1);
                gripperLCornersTransformed(5:8,1)' gripperLCornersTransformed(5,1);
                ones(1,5)*mean(gripperLCornersTransformed(5:8,1))];
            Ygl = [ones(1,5)*mean(gripperLCornersTransformed(1:4,2));
                gripperLCornersTransformed(1:4,2)' gripperLCornersTransformed(1,2);
                gripperLCornersTransformed(5:8,2)' gripperLCornersTransformed(5,2);
                ones(1,5)*mean(gripperLCornersTransformed(5:8,2))];
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Physical dimensions of right gripper - Do NOT edit %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            gripperRCorners = [L3 -20 18 1; % The 1 in last column is just for homogeneous coordinates
                L3 20 18 1;
                L3 20 25 1;
                L3 -20 25 1;
                L3+1.5*Le -20 18 1;
                L3+1.5*Le 20 18 1;
                L3+1.5*Le 20 25 1;
                L3+1.5*Le -20 25 1];
            
            gripperRCornersTransformed = T03*gripperRCorners';
            gripperRCornersTransformed =  gripperRCornersTransformed';
            
            Zgr = [ones(1,5)*mean(gripperRCornersTransformed(1:4,3));
                gripperRCornersTransformed(1:4,3)' gripperRCornersTransformed(1,3);
                gripperRCornersTransformed(5:8,3)' gripperRCornersTransformed(5,3);
                ones(1,5)*mean(gripperRCornersTransformed(5:8,3))];
            Xgr = [ones(1,5)*mean(gripperRCornersTransformed(1:4,1));
                gripperRCornersTransformed(1:4,1)' gripperRCornersTransformed(1,1);
                gripperRCornersTransformed(5:8,1)' gripperRCornersTransformed(5,1);
                ones(1,5)*mean(gripperRCornersTransformed(5:8,1))];
            Ygr = [ones(1,5)*mean(gripperRCornersTransformed(1:4,2));
                gripperRCornersTransformed(1:4,2)' gripperRCornersTransformed(1,2);
                gripperRCornersTransformed(5:8,2)' gripperRCornersTransformed(5,2);
                ones(1,5)*mean(gripperRCornersTransformed(5:8,2))];
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Calibration Tool - Do NOT edit %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            CalibToolCorners = [L3 -10 -10 1; % The 1 in last column is just for homogeneous coordinates
                L3 -10 10 1;
                L3 10 10 1;
                L3 10 -10 1
                L3 -10 -10 1;
                L3+Le 0 0 1;
                L3+Le 0 0 1;
                L3+Le 0 0 1;
                L3+Le 0 0 1;
                L3+Le 0 0 1];
            
            CalibToolCornersTransformed = T03*CalibToolCorners';
            CalibToolCornersTransformed =  CalibToolCornersTransformed';
            
            XCalibTool = [CalibToolCornersTransformed(1:5,1)';CalibToolCornersTransformed(6:10,1)'];
            YCalibTool = [CalibToolCornersTransformed(1:5,2)';CalibToolCornersTransformed(6:10,2)'];
            ZCalibTool = [CalibToolCornersTransformed(1:5,3)';CalibToolCornersTransformed(6:10,3)'];
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Checkerboard - Do NOT edit %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            numberBlackBoxesX = 5;      % Checkerboard
            numberBlackBoxesYmin = 3;   % Y has 4 and 3 boxes, use the smaller number
            checkerboardCorners = [];
            for i = 1:numberBlackBoxesX
                for j = 1:numberBlackBoxesYmin
                    checkerboardCorners = [checkerboardCorners;
                        
                        2*i-2 2*j-2;
                        2*i-1 2*j-2;
                        2*i-1 2*j-1;
                        2*i-2 2*j-1;
                        
                        2*i-1 2*j-1;
                        2*i   2*j-1;
                        2*i   2*j;
                        2*i-1 2*j];
                end
            end
            for i = 1:numberBlackBoxesX
                j = numberBlackBoxesYmin+1;
                checkerboardCorners = [checkerboardCorners;
            
                    2*i-2 2*j-2;
                    2*i-1 2*j-2;
                    2*i-1 2*j-1;
                    2*i-2 2*j-1];
            end
            checkerboardCorners(:,1) = checkerboardCorners(:,1)-1; % use these two lines to shift to the correct origin
            checkerboardCorners(:,2) = checkerboardCorners(:,2)-1; 
            checkerboardCorners3D = [checkerboardCorners*checkerboardScale, zeros(length(checkerboardCorners),1), ones(length(checkerboardCorners),1)]; % Homogeneous
            
            RzRobotWorld = [cosd(thetazRobotWorld) -sind(thetazRobotWorld) 0;
                sind(thetazRobotWorld) cosd(thetazRobotWorld) 0;
                0 0 1];
            RyRobotWorld = [cosd(thetayRobotWorld) 0 sind(thetayRobotWorld);
                0 1 0;
                -sind(thetayRobotWorld) 0 cosd(thetayRobotWorld)];
            RxRobotWorld = [1 0 0;
                0 cosd(thetaxRobotWorld) -sind(thetaxRobotWorld);
                0 sind(thetaxRobotWorld) cosd(thetaxRobotWorld)];
            RRobotWorld = RxRobotWorld*RyRobotWorld*RzRobotWorld; 
            TRobotWorld = [RRobotWorld PRobotWorld;0 0 0 1]; % world frame wrt. robot frame (fixed)
            
            checkerboardCorners3DTransformed = TRobotWorld*checkerboardCorners3D';
            checkerboardCorners3DTransformed =  checkerboardCorners3DTransformed';
            
            checkerboardCorners3DPixel = (checkerboardCorners3D(:,1:3)*RCameraWorld + PCameraWorld)*CameraIntrinsics;
            checkerboardCorners3DPixel = checkerboardCorners3DPixel./checkerboardCorners3DPixel(:,3);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % World Frame Arrows on Checkerboard - Do NOT edit %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            arrowXStart = TRobotWorld*[0;0;0;1];
            arrowXEnd = TRobotWorld*[5*checkerboardScale;0;0;1];
            arrowYStart = TRobotWorld*[0;0;0;1];
            arrowYEnd = TRobotWorld*[0;5*checkerboardScale;0;1];
            arrowZStart = TRobotWorld*[0;0;0;1];
            arrowZEnd = TRobotWorld*[0;0;5*checkerboardScale;1];
            
            arrowXStartPixel = ([0 0 0]*RCameraWorld + PCameraWorld)*CameraIntrinsics;
            arrowXStartPixel = arrowXStartPixel./arrowXStartPixel(:,3);
            arrowXEndPixel = ([5*checkerboardScale 0 0]*RCameraWorld + PCameraWorld)*CameraIntrinsics;
            arrowXEndPixel = arrowXEndPixel./arrowXEndPixel(:,3);
            arrowYStartPixel = ([0 0 0]*RCameraWorld + PCameraWorld)*CameraIntrinsics;
            arrowYStartPixel = arrowYStartPixel./arrowYStartPixel(:,3);
            arrowYEndPixel = ([0 5*checkerboardScale 0]*RCameraWorld + PCameraWorld)*CameraIntrinsics;
            arrowYEndPixel = arrowYEndPixel./arrowYEndPixel(:,3);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Cubic Box Object - Do NOT edit %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            ObjectBoxCorners = [-10 -10 20 1; % The 1 in last column is just for homogeneous coordinates
                10 -10 20 1;
                10 10 20 1;
                -10 10 20 1;
                -10 -10 0 1;
                10 -10 0 1;
                10 10 0 1;
                -10 10 0 1];
            
            RWorldObjectBox = [cosd(RzWorldObjectBox) -sind(RzWorldObjectBox) 0;   
                sind(RzWorldObjectBox) cosd(RzWorldObjectBox) 0;                   
                0 0 1];
            TRobotObjectBox = TRobotWorld*[RWorldObjectBox PWorldObjectBox;0 0 0 1]; % world wrt. robot (fixed) * object wrt. world (fixed) = object wrt. robot (fixed)
            ObjectBoxCornersTransformed = TRobotObjectBox*ObjectBoxCorners';
            ObjectBoxCornersTransformed =  ObjectBoxCornersTransformed';
            
            ZObjectBox = [ones(1,5)*mean(ObjectBoxCornersTransformed(1:4,3));
                ObjectBoxCornersTransformed(1:4,3)' ObjectBoxCornersTransformed(1,3);
                ObjectBoxCornersTransformed(5:8,3)' ObjectBoxCornersTransformed(5,3);
                ones(1,5)*mean(ObjectBoxCornersTransformed(5:8,3))];
            XObjectBox = [ones(1,5)*mean(ObjectBoxCornersTransformed(1:4,1));
                ObjectBoxCornersTransformed(1:4,1)' ObjectBoxCornersTransformed(1,1);
                ObjectBoxCornersTransformed(5:8,1)' ObjectBoxCornersTransformed(5,1);
                ones(1,5)*mean(ObjectBoxCornersTransformed(5:8,1))];
            YObjectBox = [ones(1,5)*mean(ObjectBoxCornersTransformed(1:4,2));
                ObjectBoxCornersTransformed(1:4,2)' ObjectBoxCornersTransformed(1,2);
                ObjectBoxCornersTransformed(5:8,2)' ObjectBoxCornersTransformed(5,2);
                ones(1,5)*mean(ObjectBoxCornersTransformed(5:8,2))];
            
            ObjectBoxCornersPixel = ((ObjectBoxCorners(:,1:3)*RWorldObjectBox' + PWorldObjectBox')*RCameraWorld + PCameraWorld)*CameraIntrinsics;
                    % Checkerboard under transformation between pixel and world frames
                    % RWorldObjectBox needs transpose because (Rx)' = (x' * R') for
                    % row vector
                    % RCameraWorld don't need transpose because it was already for row
                    % vector.
            ObjectBoxCornersPixel = ObjectBoxCornersPixel./ObjectBoxCornersPixel(:,3);
            
            ZObjectBoxPixel = [ones(1,5)*mean(ObjectBoxCornersPixel(1:4,3));
                ObjectBoxCornersPixel(1:4,3)' ObjectBoxCornersPixel(1,3);
                ObjectBoxCornersPixel(5:8,3)' ObjectBoxCornersPixel(5,3);
                ones(1,5)*mean(ObjectBoxCornersPixel(5:8,3))];
            XObjectBoxPixel = [ones(1,5)*mean(ObjectBoxCornersPixel(1:4,1));
                ObjectBoxCornersPixel(1:4,1)' ObjectBoxCornersPixel(1,1);
                ObjectBoxCornersPixel(5:8,1)' ObjectBoxCornersPixel(5,1);
                ones(1,5)*mean(ObjectBoxCornersPixel(5:8,1))];
            YObjectBoxPixel = [ones(1,5)*mean(ObjectBoxCornersPixel(1:4,2));
                ObjectBoxCornersPixel(1:4,2)' ObjectBoxCornersPixel(1,2);
                ObjectBoxCornersPixel(5:8,2)' ObjectBoxCornersPixel(5,2);
                ones(1,5)*mean(ObjectBoxCornersPixel(5:8,2))];
           
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Cubic Box Object Being Grabbed - Add your own code  %
            % to move the cubic object together with end-effector %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % NOTE: PLEASE SEE THIS CODE FROM LINE 215!
            
            %if app.CubeGrabbedCheckBox.Value == true
            %    PWorldObjectBox = [0.9998*(xp+100)-0.0175*(yp-200)+0.0017*(zp+20);
            %        -0.0175*(xp+100)-0.9998*(yp-200)-0.0017*(zp+20);
            %        0.0017*(xp+100)+0.0018*(yp-200)-(zp+20)];
            %else
            %    PWorldObjectBox = [-50; 100; 0];
            %end
            
                                   
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Cylindrical Object - do NOT edit %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            [Xcyl,Ycyl,Zcyl] = cylinder(10); % create cylinder with radius r = 10
            hcyl = 20;
            Zcyl = Zcyl*hcyl; % scale height by hcyl
            
            [mcyl,ncyl]=size(Xcyl);
            
            RWorldObjectCylinder= [1 0 0;0 1 0;0 0 1]; % for cylinder, rotation about z doesn't have influence    
            TRobotObjectCylinder = TRobotWorld*[RWorldObjectCylinder PWorldObjectCylinder;0 0 0 1]; % world wrt. robot (fixed) * object wrt. world (fixed) = object wrt. robot (fixed)
            for i=1:mcyl
                for j=1:ncyl
                    XcylTransformed(i,j) = TRobotObjectCylinder(1,:)*[Xcyl(i,j);Ycyl(i,j);Zcyl(i,j);1];
                    YcylTransformed(i,j) = TRobotObjectCylinder(2,:)*[Xcyl(i,j);Ycyl(i,j);Zcyl(i,j);1];
                    ZcylTransformed(i,j) = TRobotObjectCylinder(3,:)*[Xcyl(i,j);Ycyl(i,j);Zcyl(i,j);1];
                    
                    ObjectCylinderPixel(i,j,:) = (([Xcyl(i,j),Ycyl(i,j),Zcyl(i,j)]*RWorldObjectCylinder' + PWorldObjectCylinder')*RCameraWorld + PCameraWorld)*CameraIntrinsics;
                            % Checkerboard under transformation between pixel and world frames
                            % RWorldObjectCylinder needs transpose because (Rx)' = (x' * R') for
                            % row vector
                            % RCameraWorld don't need transpose because it was already for row
                            % vector.
                    ObjectCylinderPixel(i,j,:) = ObjectCylinderPixel(i,j,:)./ObjectCylinderPixel(i,j,3);
                    
                    XcylPixel(i,j) = ObjectCylinderPixel(i,j,1);
                    YcylPixel(i,j) = ObjectCylinderPixel(i,j,2);
                    ZcylPixel(i,j) = ObjectCylinderPixel(i,j,3);
                    
                end
            end
 
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Cylindrical Object Being Grabbed - Add your own code %
            % to move the cylinder together with end-effector      %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % NOTE:PLEASE SEE THIS CODE FROM LINE 224!
            
            %if app.CylinderGrabbedCheckBox.Value == true
            %    PWorldObjectCylinder = [0.9998*(xp+100)-0.0175*(yp-200)+0.0017*(zp+20);
            %        -0.0175*(xp+100)-0.9998*(yp-200)-0.0017*(zp+20);
            %        0.0017*(xp+100)+0.0018*(yp-200)-(zp+20)];
            %else
            %    PWorldObjectCylinder = [100; 50; 0];
            %end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Plot everything from isometric view - Do NOT edit %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            plot3(app.UIAxes,[0 x2],[0 y2],[0 z2],'LineWidth',12); % Plot robot link 1
            axis(app.UIAxes,[-(L1+L2+L3+Le)*1.2 (L1+L2+L3+Le)*1.2 -(L1+L2+L3+Le)*1.2 (L1+L2+L3+Le)*1.2 -(L1+L2+L3+Le)*1.2 (L1+L2+L3+Le)*1.2]);
            xlabel(app.UIAxes,'x');
            ylabel(app.UIAxes,'y');
            zlabel(app.UIAxes,'z');
            grid(app.UIAxes,'on') 
            AzimuthValue = app.AzimuthSlider.Value;
            ElevationValue = app.ElevationSlider.Value;
            view(app.UIAxes,AzimuthValue,ElevationValue);
            hold(app.UIAxes,'on')
            surf(app.UIAxes,X2,Y2,Z2,'FaceColor','b'); % Plot robot link 2
            surf(app.UIAxes,X3,Y3,Z3,'FaceColor','b'); % Plot robot link 3
            [m,n]=size(Z3);
            surf(app.UIAxes,X2,Y2,ones(m,n)*(-L1-L2-L3-Le)*1.2,'FaceColor',[0.8 0.8 0.8],'EdgeColor',[0.8 0.8 0.8]);   % plot robot shadow
            surf(app.UIAxes,X3,Y3,ones(m,n)*(-L1-L2-L3-Le)*1.2,'FaceColor',[0.8 0.8 0.8],'EdgeColor',[0.8 0.8 0.8]);
            
            plot3(app.UIAxes,xp,yp,zp,'r.','markersize',8); % plot TCP

            quiver3(app.UIAxes,arrowXStart(1),arrowXStart(2),arrowXStart(3),arrowXEnd(1)-arrowXStart(1),arrowXEnd(2)-arrowXStart(2),arrowXEnd(3)-arrowXStart(3),...
                'MaxHeadSize',0.6,'LineWidth',2,'Color','r'); % plot world frame
            quiver3(app.UIAxes,arrowYStart(1),arrowYStart(2),arrowYStart(3),arrowYEnd(1)-arrowYStart(1),arrowYEnd(2)-arrowYStart(2),arrowYEnd(3)-arrowYStart(3),...
                'MaxHeadSize',0.6,'LineWidth',2,'Color','g');
            quiver3(app.UIAxes,arrowZStart(1),arrowZStart(2),arrowZStart(3),arrowZEnd(1)-arrowZStart(1),arrowZEnd(2)-arrowZStart(2),arrowZEnd(3)-arrowZStart(3),...
                'MaxHeadSize',0.6,'LineWidth',2,'Color','b');
            
            if app.ShowGripperCheckBox.Value % plot gripper and shadow
                surf(app.UIAxes,Xgl,Ygl,Zgl,'FaceColor','b');
                surf(app.UIAxes,Xgr,Ygr,Zgr,'FaceColor','b');
                surf(app.UIAxes,Xgl,Ygl,ones(m,n)*(-L1-L2-L3-Le)*1.2,'FaceColor',[0.8 0.8 0.8],'EdgeColor',[0.8 0.8 0.8]);
                surf(app.UIAxes,Xgr,Ygr,ones(m,n)*(-L1-L2-L3-Le)*1.2,'FaceColor',[0.8 0.8 0.8],'EdgeColor',[0.8 0.8 0.8]);
            end
            
            if app.ShowCalibToolCheckBox.Value % plot calibration tool and shadow
                [mCalib,nCalib]=size(ZCalibTool);
                surf(app.UIAxes,XCalibTool,YCalibTool,ZCalibTool,'FaceColor','b');
                surf(app.UIAxes,XCalibTool,YCalibTool,ones(mCalib,nCalib)*(-L1-L2-L3-Le)*1.2,'FaceColor',[0.8 0.8 0.8],'EdgeColor',[0.8 0.8 0.8]);
            end
            
            if app.ShowCheckerboardCheckBox.Value % plot checkerboard
                fill3(app.UIAxes,checkerboardCorners3DTransformed(1:4,1),checkerboardCorners3DTransformed(1:4,2),checkerboardCorners3DTransformed(1:4,3),'k'); % plot checkerboard
                for k = 2:length(checkerboardCorners)/4
                    fill3(app.UIAxes,checkerboardCorners3DTransformed(4*k-3:4*k,1),checkerboardCorners3DTransformed(4*k-3:4*k,2),checkerboardCorners3DTransformed(4*k-3:4*k,3),'k');
                end
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Plot objects - ADD if/else to move object           %
            % together with end-effector, if gripper is activated %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            if app.ShowObjectsCheckBox.Value % plot object
                surf(app.UIAxes,XObjectBox,YObjectBox,ZObjectBox,'FaceColor','c'); % plot cube

                surf(app.UIAxes,XcylTransformed,YcylTransformed,ZcylTransformed,'FaceColor','y'); % plot cylinder
                fill3(app.UIAxes,XcylTransformed(1,:),YcylTransformed(1,:),ZcylTransformed(1,:),'y') % plot cylinder faces
                fill3(app.UIAxes,XcylTransformed(2,:),YcylTransformed(2,:),ZcylTransformed(2,:),'y') % plot cylinder faces
            end
    
            hold(app.UIAxes,'off') % Do NOT edit this line.
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % Plot object and checkerboard in camera view - Do NOT edit %
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    
            plot3(app.UIAxes2,arrowXStartPixel(1),arrowXStartPixel(2),arrowXStartPixel(3),'Color','k');   % plot world frame origin
            view(app.UIAxes2,0,90);
            xlabel(app.UIAxes2,'x');
            ylabel(app.UIAxes2,'y');
            zlabel(app.UIAxes2,'z');
            grid(app.UIAxes2,'on') 
            axis(app.UIAxes2,'equal') 
            axis(app.UIAxes2,[0 640 0 480 -100 100]);
            set(app.UIAxes2, 'YDir','reverse')
            
            hold(app.UIAxes2,'on')
            
            if app.ShowCheckerboardCheckBox.Value % plot checkerboard
                fill3(app.UIAxes2,checkerboardCorners3DPixel(1:4,1),checkerboardCorners3DPixel(1:4,2),checkerboardCorners3DPixel(1:4,3),'k'); % plot checkerboard
                for k = 2:length(checkerboardCorners)/4
                    fill3(app.UIAxes2,checkerboardCorners3DPixel(4*k-3:4*k,1),checkerboardCorners3DPixel(4*k-3:4*k,2),checkerboardCorners3DPixel(4*k-3:4*k,3),'k');
                end
            end
            
            if app.ShowObjectsCheckBox.Value % plot object in grey scale
                surf(app.UIAxes2,XObjectBoxPixel,YObjectBoxPixel,ZObjectBoxPixel,'FaceColor',[0 0.5 0],'EdgeColor',[0 0.5 0.5]); % plot cube
                fill3(app.UIAxes2,ObjectBoxCornersPixel(5:8,1),ObjectBoxCornersPixel(5:8,2),ObjectBoxCornersPixel(5:8,3),'c'); % plot top surface
                plot3(app.UIAxes2,[ObjectBoxCornersPixel(5,1),ObjectBoxCornersPixel(7,1)],[ObjectBoxCornersPixel(5,2),ObjectBoxCornersPixel(7,2)],[ObjectBoxCornersPixel(5,3),ObjectBoxCornersPixel(7,3)],'Color','k'); % plot center of top surface
                plot3(app.UIAxes2,[ObjectBoxCornersPixel(6,1),ObjectBoxCornersPixel(8,1)],[ObjectBoxCornersPixel(6,2),ObjectBoxCornersPixel(8,2)],[ObjectBoxCornersPixel(6,3),ObjectBoxCornersPixel(8,3)],'Color','k');
            
                surf(app.UIAxes2,XcylPixel,YcylPixel,ZcylPixel,'FaceColor',[0.5 0.5 0],'EdgeColor',[0.5 0.5 0]); % plot cylinder
                fill3(app.UIAxes2,XcylPixel(1,:),YcylPixel(1,:),ZcylPixel(1,:),'y') % plot top surface
                plot3(app.UIAxes2,mean(XcylPixel(1,:)),mean(YcylPixel(1,:)),mean(ZcylPixel(1,:)),'.','Color','k'); % plot center on top surface
            end
            
            quiver3(app.UIAxes2,arrowXStartPixel(1),arrowXStartPixel(2),arrowXStartPixel(3),arrowXEndPixel(1)-arrowXStartPixel(1),arrowXEndPixel(2)-arrowXStartPixel(2),arrowXEndPixel(3)-arrowXStartPixel(3),...
                'MaxHeadSize',0.6,'LineWidth',2,'Color','r');   % plot world frame
            quiver3(app.UIAxes2,arrowYStartPixel(1),arrowYStartPixel(2),arrowYStartPixel(3),arrowYEndPixel(1)-arrowYStartPixel(1),arrowYEndPixel(2)-arrowYStartPixel(2),arrowYEndPixel(3)-arrowYStartPixel(3),...
                'MaxHeadSize',0.6,'LineWidth',2,'Color','g');   % plot world frame
            
            hold(app.UIAxes2,'off')
           
        end

        % Value changed function: AzimuthSlider
        function AzimuthSliderValueChanged(app, event)
            
            %%%%%%%%%%%%%%%%%%%%
            % Do NOT edit this %
            %%%%%%%%%%%%%%%%%%%%
            
            value = app.AzimuthSlider.Value;
            
            ForwardButtonPushed(app, event)
            
        end

        % Value changed function: ElevationSlider
        function ElevationSliderValueChanged(app, event)
            
            %%%%%%%%%%%%%%%%%%%%
            % Do NOT edit this %
            %%%%%%%%%%%%%%%%%%%%            
            
            value = app.ElevationSlider.Value;
            
            ForwardButtonPushed(app, event)
            
        end

        % Value changed function: ShowCheckerboardCheckBox
        function ShowCheckerboardCheckBoxValueChanged(app, event)
            
            %%%%%%%%%%%%%%%%%%%%
            % Do NOT edit this %
            %%%%%%%%%%%%%%%%%%%%            
            
            value = app.ShowCheckerboardCheckBox.Value;
            
            ForwardButtonPushed(app, event)
            
        end

        % Value changed function: ShowObjectsCheckBox
        function ShowObjectsCheckBoxValueChanged(app, event)
            
            %%%%%%%%%%%%%%%%%%%%
            % Do NOT edit this %
            %%%%%%%%%%%%%%%%%%%%            
            
            value = app.ShowObjectsCheckBox.Value;
            
            ForwardButtonPushed(app, event)
            
        end

        % Value changed function: ShowCalibToolCheckBox
        function ShowCalibToolCheckBoxValueChanged(app, event)
            
            %%%%%%%%%%%%%%%%%%%%
            % Do NOT edit this %
            %%%%%%%%%%%%%%%%%%%%            
            
            value = app.ShowCalibToolCheckBox.Value;
            
            ForwardButtonPushed(app, event)
            
        end

        % Value changed function: ShowGripperCheckBox
        function ShowGripperCheckBoxValueChanged(app, event)
            
            %%%%%%%%%%%%%%%%%%%%
            % Do NOT edit this %
            %%%%%%%%%%%%%%%%%%%%            
            
            value = app.ShowGripperCheckBox.Value;
            
            ForwardButtonPushed(app, event)
            
        end

        % Value changed function: PixelXEditField
        function PixelXEditFieldValueChanged(app, event)
            
            %%%%%%%%%%%%%%%%%%%%
            % Do NOT edit this %
            %%%%%%%%%%%%%%%%%%%%            
            
            value = app.PixelXEditField.Value;
            
        end

        % Value changed function: PixelYEditField
        function PixelYEditFieldValueChanged(app, event)
            
            %%%%%%%%%%%%%%%%%%%%
            % Do NOT edit this %
            %%%%%%%%%%%%%%%%%%%%           
            
            value = app.PixelYEditField.Value;
            
        end

        % Value changed function: ViewListBox
        function ViewListBoxValueChanged(app, event)
            
            %%%%%%%%%%%%%%%%%%%%
            % Do NOT edit this %
            %%%%%%%%%%%%%%%%%%%%            
            
            value = app.ViewListBox.Value;           

            ViewListValue = app.ViewListBox.Value;
            
            if(strcmp(ViewListValue,'Default View'))
                set(app.AzimuthSlider, 'Value', -37.5) % So that slider moves according to value
                set(app.ElevationSlider, 'Value', 30) 
            elseif(strcmp(ViewListValue,'Front View'))
                set(app.AzimuthSlider, 'Value', 0) % So that slider moves according to value
                set(app.ElevationSlider, 'Value', 0) 
            elseif(strcmp(ViewListValue,'Back View'))
                set(app.AzimuthSlider, 'Value', 180) % So that slider moves according to value
                set(app.ElevationSlider, 'Value', 0) 
            elseif(strcmp(ViewListValue,'Top View'))
                set(app.AzimuthSlider, 'Value', 0) % So that slider moves according to value
                set(app.ElevationSlider, 'Value', 90) 
            elseif(strcmp(ViewListValue,'Bottom View'))
                set(app.AzimuthSlider, 'Value', 0) % So that slider moves according to value
                set(app.ElevationSlider, 'Value', -90) 
            elseif(strcmp(ViewListValue,'Right View'))
                set(app.AzimuthSlider, 'Value', 90) % So that slider moves according to value
                set(app.ElevationSlider, 'Value', 0) 
            elseif(strcmp(ViewListValue,'Left View'))
                set(app.AzimuthSlider, 'Value', -90) % So that slider moves according to value
                set(app.ElevationSlider, 'Value', 0) 
            end
            
            ForwardButtonPushed(app, event)
            
        end

        % Button pushed function: SnapCameraButton
        function SnapCameraButtonPushed(app, event)

            %%%%%%%%%%%%%%%%%%%%
            % Do NOT edit this %
            %%%%%%%%%%%%%%%%%%%%           
            
            fignew = figure; % Create new figure
            axNew = axes; % Create new axes
            
            copyobj(app.UIAxes2.Children, axNew) % Copy all objects from UIAxes to new axis
            
            uiAxParams = get(app.UIAxes2); % Save all parameters of the UIAxes
            uiAxParamNames = fieldnames(uiAxParams); 
            
            editableParams = fieldnames(set(axNew)); % Get list of editable params in new axis
            
            % Remove the UIAxes params that aren't editable in the new axes (add others you don't want)
            badFields = uiAxParamNames(~ismember(uiAxParamNames, editableParams)); 
            badFields = [badFields; 'Parent'; 'Children'; 'XAxis'; 'YAxis'; 'ZAxis';'Position';'OuterPosition']; 
            uiAxGoodParams = rmfield(uiAxParams,badFields); 
            
            set(axNew, uiAxGoodParams) % set editable params on new axes
            
            savefig(fignew,'CameraView.fig');
            delete(fignew);
            
        end

        % Value changed function: CartesianXEditField
        function CartesianXEditFieldValueChanged(app, event)
            value = app.CartesianXEditField.Value;
            
        end

        % Value changed function: CartesianYEditField
        function CartesianYEditFieldValueChanged(app, event)
            value = app.CartesianYEditField.Value;
            
        end

        % Value changed function: CartesianZEditField
        function CartesianZEditFieldValueChanged(app, event)
            value = app.CartesianZEditField.Value;
        end

        % Button pushed function: InverseButton
        function InverseButtonPushed(app, event)
            x = app.CartesianXEditField.Value; % read X value
            y = app.CartesianYEditField.Value; % read Y value
            z = app.CartesianZEditField.Value; % read Z value

            if sqrt(x^2+y^2+z^2)<=300 % within the workspace, the promgramme starts
                app.NotinworkspaceLamp.Enable = 'off';
                if x == 0 && y == 0 && z == 0 % when the Cartesian position of the end-effector are all zero
                    if app.Theta3EditField.Value > 0 % check the previous config, elbow-down case:
                        app.Theta3EditField.Value = 180;
                        ForwardButtonPushed(app, event); % call forward button function
                    else % elbow-up case:
                        app.Theta3EditField.Value = -180;
                        ForwardButtonPushed(app, event); % call forward button function
                    end
                    
                elseif app.Theta3EditField.Value > 0 % check the previous config, for elbow-down situation:
                    app.Theta1EditField.Value = atan2d(y,x);
                    app.Theta2EditField.Value = atan2d(z,sqrt(x^2+y^2))-acosd((x^2+y^2+z^2)/(2*sqrt(x^2+y^2+z^2)*150));
                    app.Theta3EditField.Value = acosd((x^2+y^2+z^2-2*(150)^2)/(2*(150^2)));
                    ForwardButtonPushed(app, event); % call forward button function
                    
                else % for elbow-up situation:
                    app.Theta1EditField.Value = atan2d(y,x);
                    app.Theta2EditField.Value = atan2d(z,sqrt(x^2+y^2))+acosd((x^2+y^2+z^2)/(2*sqrt(x^2+y^2+z^2)*150));
                    app.Theta3EditField.Value = -acosd((x^2+y^2+z^2-2*(150)^2)/(2*(150^2)));
                    ForwardButtonPushed(app, event); % call forward button function
                end
            else
                app.NotinworkspaceLamp.Enable = 'on'; % outside the workspace, the alarm is red!
            end
        end

        % Button pushed function: FwdTrajectoryButton
        function FwdTrajectoryButtonPushed(app, event)
            % boundary conditions:
            % obtain intial theta 1-3 from global variables in FwdButton
            global theta1_0;
            global theta2_0;
            global theta3_0;
            
            % otain the inputted final theta 1-3 values
            theta1_f = app.Theta1EditField.Value;
            theta2_f = app.Theta2EditField.Value;
            theta3_f = app.Theta3EditField.Value;
            
            % time taken, assume it is 2 seconds
            tf = 2;
            
            % calculate cubic polynomial constant terms
            % for theta 1:
            a1_0 = theta1_0;
            a1_1 = 0;
            a1_2 = 3/tf^2*(theta1_f - theta1_0);
            a1_3 = -2/tf^3*(theta1_f - theta1_0);
            
            % for theta 2:
            a2_0 = theta2_0;
            a2_1 = 0;
            a2_2 = 3/tf^2*(theta2_f - theta2_0);
            a2_3 = -2/tf^3*(theta2_f - theta2_0);
            
            % for theta 3:
            a3_0 = theta3_0;
            a3_1 = 0;
            a3_2 = 3/tf^2*(theta3_f - theta3_0);
            a3_3 = -2/tf^3*(theta3_f - theta3_0);
            
            for t = 0:0.1:tf
                % joint space schemes based on cubic polynomial
                theta1Cubic = a1_0+a1_1*t+a1_2*t^2+a1_3*t^3;
                theta2Cubic = a2_0+a2_1*t+a2_2*t^2+a2_3*t^3;
                theta3Cubic = a3_0+a3_1*t+a3_2*t^2+a3_3*t^3;
                
                % display the trajectory planning for each point
                app.Theta1EditField.Value = theta1Cubic;
                app.Theta2EditField.Value = theta2Cubic;
                app.Theta3EditField.Value = theta3Cubic;
                
                % call FwdButton to run
                ForwardButtonPushed(app,event)
                
                % pause and continue the for loop every 0.001s to show the trajectory
                pause(0.001) 
            end
        end

        % Button pushed function: InvTrajectoryButton
        function InvTrajectoryButtonPushed(app, event)
            % boundary conditions:
            % obtain intial XYZ from global variables in FwdButton
            global x_0;
            global y_0;
            global z_0;
            
            % otain the inputted final XYZ values
            x_f = app.CartesianXEditField.Value;
            y_f = app.CartesianYEditField.Value;
            z_f = app.CartesianZEditField.Value;
            
            % time taken, assume it is 2 seconds
            tf = 2;
            
            % calculate cubic polynomial constant terms
            % for X:
            ax_0 = x_0;
            ax_1 = 0;
            ax_2 = 3/tf^2*(x_f-x_0);
            ax_3 = -2/tf^3*(x_f-x_0);
            
            % for y:
            ay_0 = y_0;
            ay_1 = 0;
            ay_2 = 3/tf^2*(y_f-y_0);
            ay_3 = -2/tf^3*(y_f-y_0);
            
            % for z:
            az_0 = z_0;
            az_1 = 0;
            az_2 = 3/tf^2*(z_f-z_0);
            az_3 = -2/tf^3*(z_f-z_0);
            
            for t = 0 : 0.1 : tf
                % cartesian space schemes based on cubic polynomial
                xCubic = ax_0+ax_1*t+ax_2*t^2+ax_3*t^3;
                yCubic = ay_0+ay_1*t+ay_2*t^2+ay_3*t^3;
                zCubic = az_0+az_1*t+az_2*t^2+az_3*t^3;
                
                % display the trajectory planning for each point
                app.CartesianXEditField.Value = xCubic;
                app.CartesianYEditField.Value = yCubic;
                app.CartesianZEditField.Value = zCubic;
                
                % call InvButton to run
                InverseButtonPushed(app,event)
                
                % pause and continue the for loop every 0.001s to show the trajectory
                pause(0.001) 
            end
            
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.Theta1EditField.Value = 10 + app.Theta1EditField.Value;
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.Theta1EditField.Value = app.Theta1EditField.Value - 10;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.Theta2EditField.Value = 10 + app.Theta2EditField.Value;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.Theta2EditField.Value = app.Theta2EditField.Value - 10;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.Theta3EditField.Value = 10 + app.Theta3EditField.Value;
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.Theta3EditField.Value = app.Theta3EditField.Value - 10;
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.CartesianXEditField.Value = 10 + app.CartesianXEditField.Value;
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            app.CartesianXEditField.Value = app.CartesianXEditField.Value - 10;
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.CartesianYEditField.Value = 10 + app.CartesianYEditField.Value;
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            app.CartesianYEditField.Value = app.CartesianYEditField.Value - 10;
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.CartesianZEditField.Value = 10 + app.CartesianZEditField.Value;
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            app.CartesianZEditField.Value = app.CartesianZEditField.Value - 10;
        end

        % Button pushed function: ApproachObjectButton
        function ApproachObjectButtonPushed(app, event)
            % boundary conditions:
            % obtain intial XYZ from global variables in FwdButton
            global x_0;
            global y_0;
            global z_0;
            
            % otain the inputted final XYZ values
            x_f = app.CartesianXEditField.Value;
            y_f = app.CartesianYEditField.Value;
            z_f = app.CartesianZEditField.Value + 50;
            
            % time taken
            tf = 2;
            
            % calculate cubic polynomial constant terms
            % for X:
            ax_0 = x_0;
            ax_1 = 0;
            ax_2 = 3/tf^2*(x_f-x_0);
            ax_3 = -2/tf^3*(x_f-x_0);
            
            % for y:
            ay_0 = y_0;
            ay_1 = 0;
            ay_2 = 3/tf^2*(y_f-y_0);
            ay_3 = -2/tf^3*(y_f-y_0);
            
            % for z:
            az_0 = z_0;
            az_1 = 0;
            az_2 = 3/tf^2*(z_f-z_0);
            az_3 = -2/tf^3*(z_f-z_0);
            
            % move above(50mm) the object in 2 seconds
            for t = 0 : 0.1 : tf
                % cartesian space schemes based on cubic polynomial
                xCubic = ax_0+ax_1*t+ax_2*t^2+ax_3*t^3;
                yCubic = ay_0+ay_1*t+ay_2*t^2+ay_3*t^3;
                zCubic = az_0+az_1*t+az_2*t^2+az_3*t^3;
                
                % display the trajectory planning for each point
                app.CartesianXEditField.Value = xCubic;
                app.CartesianYEditField.Value = yCubic;
                app.CartesianZEditField.Value = zCubic;
                
                % call InvButton to run
                InverseButtonPushed(app,event)
                
                % pause and continue the for loop every 0.001s to show the trajectory
                pause(0.001) 
            end
            
            % hold the position for another 2 seconds version 1
            for t = 0 : 0.1 : tf
                 %let t=2, then it is still
                app.CartesianXEditField.Value = ax_0+ax_1*2+ax_2*2^2+ax_3*2^3;
                app.CartesianYEditField.Value = ay_0+ay_1*2+ay_2*2^2+ay_3*2^3;
                app.CartesianZEditField.Value = az_0+az_1*2+az_2*2^2+az_3*2^3;
                
                InverseButtonPushed(app,event)
                pause(0.001)
            end
            
            % hold the position for another 2 seconds version 2
            %pause(2)
            
            % then approaching the target in 2 seconds
            % boundary conditions:
            % new intial postions
            x_new_0 = ax_0+ax_1*2+ax_2*2^2+ax_3*2^3;
            y_new_0 = ay_0+ay_1*2+ay_2*2^2+ay_3*2^3;
            z_new_0 = az_0+az_1*2+az_2*2^2+az_3*2^3;
            
            % otain the new final XYZ values
            x_new_f = app.CartesianXEditField.Value;
            y_new_f = app.CartesianYEditField.Value;
            z_new_f = app.CartesianZEditField.Value - 50;           
            
            % calculate new cubic polynomial constant terms
            % for X:
            ax_new_0 = x_new_0;
            ax_new_1 = 0;
            ax_new_2 = 3/tf^2*(x_new_f-x_new_0);
            ax_new_3 = -2/tf^3*(x_new_f-x_new_0);
            
            % for y:
            ay_new_0 = y_new_0;
            ay_new_1 = 0;
            ay_new_2 = 3/tf^2*(y_new_f-y_new_0);
            ay_new_3 = -2/tf^3*(y_new_f-y_new_0);
            
            % for z:
            az_new_0 = z_new_0;
            az_new_1 = 0;
            az_new_2 = 3/tf^2*(z_new_f-z_new_0);
            az_new_3 = -2/tf^3*(z_new_f-z_new_0);
            
            % approach the object in 2 seconds
            for t = 0 : 0.1 : tf
                % cartesian space schemes based on cubic polynomial
                xnewCubic = ax_new_0+ax_new_1*t+ax_new_2*t^2+ax_new_3*t^3;
                ynewCubic = ay_new_0+ay_new_1*t+ay_new_2*t^2+ay_new_3*t^3;
                znewCubic = az_new_0+az_new_1*t+az_new_2*t^2+az_new_3*t^3;
                
                % display the trajectory planning for each point
                app.CartesianXEditField.Value = xnewCubic;
                app.CartesianYEditField.Value = ynewCubic;
                app.CartesianZEditField.Value = znewCubic;
                
                % call InvButton to run
                InverseButtonPushed(app,event)
                
                % pause and continue the for loop every 0.001s to show the trajectory
                pause(0.001) 
            end
        end

        % Button pushed function: GrabCubeButton
        function GrabCubeButtonPushed(app, event)
            % default position vector of cube wrt. world
            a = -50;
            b = 100;
            c = 0;
            % transfer to default position vector of cube wrt. robot
            x_cube = 0.9998*a-0.0175*b+0.0017*c-100;
            y_cube = -0.0175*a-0.9998*b+0.0017*c+200;
            z_cube = 0.0017*a-0.0018*b-c-20;
            
            % test whether the cube position and current end-effector XYZ
            % in a suitable range
            if x_cube < app.CartesianXEditField.Value+1 && x_cube > app.CartesianXEditField.Value-1 ...
            && y_cube < app.CartesianYEditField.Value+1 && y_cube > app.CartesianYEditField.Value-1 ...
            && z_cube < app.CartesianZEditField.Value+1 && z_cube > app.CartesianZEditField.Value-1
                app.CubeGrabbedCheckBox.Value = true;
                ForwardButtonPushed(app, event);
            else
                app.CubeGrabbedCheckBox.Value = false;
            end            
        end

        % Value changed function: CubeGrabbedCheckBox
        function CubeGrabbedCheckBoxValueChanged(app, event)
            ForwardButtonPushed(app,event)
        end

        % Button pushed function: GrabCylinderButton
        function GrabCylinderButtonPushed(app, event)
            % default position vector of cube wrt. world
            a = 100;
            b = 50;
            c = 0;
            % transfer to default position vector of cude wrt. robot
            x_cylinder = 0.9998*a-0.0175*b+0.0017*c-100;
            y_cylinder = -0.0175*a-0.9998*b+0.0017*c+200;
            z_cylinder = 0.0017*a-0.0018*b-c-20;
            
            % test whether the cube position and current end-effector XYZ
            % are similar or in a suitable range
            if x_cylinder < app.CartesianXEditField.Value+1 && x_cylinder > app.CartesianXEditField.Value-1 ...
            && y_cylinder < app.CartesianYEditField.Value+1 && y_cylinder > app.CartesianYEditField.Value-1 ...
            && z_cylinder < app.CartesianZEditField.Value+1 && z_cylinder > app.CartesianZEditField.Value-1
                app.CylinderGrabbedCheckBox.Value = true;
                ForwardButtonPushed(app, event);
            else
                app.CylinderGrabbedCheckBox.Value = false;
            end            
        end

        % Value changed function: CylinderGrabbedCheckBox
        function CylinderGrabbedCheckBoxValueChanged(app, event)
            ForwardButtonPushed(app,event)
        end

        % Button pushed function: ApproachCubeButton
        function ApproachCubeButtonPushed(app, event)
            % default position vector of cube wrt. world
            a = -50;
            b = 100;
            c = 0;
            
            % transfer to default position vector of cube wrt. robot
            x_f = 0.9998*a-0.0175*b+0.0017*c-100;
            y_f = -0.0175*a-0.9998*b+0.0017*c+200;
            z_f = 0.0017*a-0.0018*b-c-20+50; % above the cube(50mm)
            
            app.CartesianXEditField.Value = x_f;
            app.CartesianYEditField.Value = y_f;
            app.CartesianZEditField.Value = z_f;
            InvTrajectoryButtonPushed(app,event) % call InvTrajectory fun
            
            %stay constant for 2s            
            app.CartesianXEditField.Value = x_f;
            app.CartesianYEditField.Value = y_f;
            app.CartesianZEditField.Value = z_f;
            InvTrajectoryButtonPushed(app,event)
            
            % version 2
            % pause(2)
            
            z_f_new = 0.0017*a-0.0018*b-c-20; %approach the cube from above position
            
            app.CartesianXEditField.Value = x_f;
            app.CartesianYEditField.Value = y_f;
            app.CartesianZEditField.Value = z_f_new;
            InvTrajectoryButtonPushed(app,event)

        end

        % Button pushed function: ApproachCylinderButton
        function ApproachCylinderButtonPushed(app, event)
            % default position vector of cylinder wrt. world
            a = 100;
            b = 50;
            c = 0;
            
            % transfer to default position vector of cylinder wrt. robot
            x_f = 0.9998*a-0.0175*b+0.0017*c-100;
            y_f = -0.0175*a-0.9998*b+0.0017*c+200;
            z_f = 0.0017*a-0.0018*b-c-20+50; % above the cylinder(50mm)
            
            app.CartesianXEditField.Value = x_f;
            app.CartesianYEditField.Value = y_f;
            app.CartesianZEditField.Value = z_f;
            InvTrajectoryButtonPushed(app,event) % call InvTrajectory fun
            
            %stay constant for 2s            
            app.CartesianXEditField.Value = x_f;
            app.CartesianYEditField.Value = y_f;
            app.CartesianZEditField.Value = z_f;
            InvTrajectoryButtonPushed(app,event)
            
            
            z_f_new = 0.0017*a-0.0018*b-c-20; %approach the cube from above position
            
            app.CartesianXEditField.Value = x_f;
            app.CartesianYEditField.Value = y_f;
            app.CartesianZEditField.Value = z_f_new;
            InvTrajectoryButtonPushed(app,event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Theta1EditFieldLabel = uilabel(app.UIFigure);
            app.Theta1EditFieldLabel.Text = 'Theta 1';
            app.Theta1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta1EditField.ValueDisplayFormat = '%.2f';
            app.Theta1EditField.ValueChangedFcn = createCallbackFcn(app, @Theta1EditFieldValueChanged, true);
            app.Theta2EditFieldLabel = uilabel(app.UIFigure);
            app.Theta2EditFieldLabel.Text = 'Theta 2';
            app.Theta2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta2EditField.ValueDisplayFormat = '%.2f';
            app.Theta2EditField.ValueChangedFcn = createCallbackFcn(app, @Theta2EditFieldValueChanged, true);
            app.Theta3EditFieldLabel = uilabel(app.UIFigure);
            app.Theta3EditFieldLabel.Text = 'Theta 3';
            app.Theta3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta3EditField.ValueDisplayFormat = '%.2f';
            app.Theta3EditField.ValueChangedFcn = createCallbackFcn(app, @Theta3EditFieldValueChanged, true);
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.AzimuthSliderLabel = uilabel(app.UIFigure);
            app.AzimuthSliderLabel.Text = 'Azimuth';
            app.AzimuthSlider = uislider(app.UIFigure);
            app.AzimuthSlider.Limits = [-180 180];
            app.AzimuthSlider.MajorTicks = [-180 -90 0 90 180];
            app.AzimuthSlider.ValueChangedFcn = createCallbackFcn(app, @AzimuthSliderValueChanged, true);
            app.AzimuthSlider.MinorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.AzimuthSlider.Value = -37.5;
            app.ElevationSliderLabel = uilabel(app.UIFigure);
            app.ElevationSliderLabel.Text = 'Elevation';
            app.ElevationSlider = uislider(app.UIFigure);
            app.ElevationSlider.Limits = [-180 180];
            app.ElevationSlider.MajorTicks = [-180 -90 0 90 180];
            app.ElevationSlider.ValueChangedFcn = createCallbackFcn(app, @ElevationSliderValueChanged, true);
            app.ElevationSlider.MinorTicks = [-180 -135 -90 -45 0 45 90 135 180];
            app.ElevationSlider.Value = 30;
            app.ShowCheckerboardCheckBox = uicheckbox(app.UIFigure);
            app.ShowCheckerboardCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowCheckerboardCheckBoxValueChanged, true);
            app.ShowCheckerboardCheckBox.Text = 'Show Checkerboard';
            app.ShowCheckerboardCheckBox.Value = true;
            app.ShowObjectsCheckBox = uicheckbox(app.UIFigure);
            app.ShowObjectsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowObjectsCheckBoxValueChanged, true);
            app.ShowObjectsCheckBox.Text = 'Show Objects';
            app.ShowObjectsCheckBox.Value = true;
            app.ShowCalibToolCheckBox = uicheckbox(app.UIFigure);
            app.ShowCalibToolCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowCalibToolCheckBoxValueChanged, true);
            app.ShowCalibToolCheckBox.Text = 'Show Calib Tool';
            app.ShowGripperCheckBox = uicheckbox(app.UIFigure);
            app.ShowGripperCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowGripperCheckBoxValueChanged, true);
            app.ShowGripperCheckBox.Text = 'Show Gripper';
            app.ShowGripperCheckBox.Value = true;
            app.PixelXEditFieldLabel = uilabel(app.UIFigure);
            app.PixelXEditFieldLabel.Text = 'Pixel X';
            app.PixelXEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelXEditField.ValueDisplayFormat = '%.0f';
            app.PixelXEditField.ValueChangedFcn = createCallbackFcn(app, @PixelXEditFieldValueChanged, true);
            app.PixelYEditFieldLabel = uilabel(app.UIFigure);
            app.PixelYEditFieldLabel.Text = 'Pixel Y';
            app.PixelYEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelYEditField.ValueDisplayFormat = '%.0f';
            app.PixelYEditField.ValueChangedFcn = createCallbackFcn(app, @PixelYEditFieldValueChanged, true);
            app.ViewListBoxLabel = uilabel(app.UIFigure);
            app.ViewListBoxLabel.Text = 'View';
            app.ViewListBox = uilistbox(app.UIFigure);
            app.ViewListBox.Items = {'Default View', 'Top View', 'Bottom View', 'Front View', 'Back View', 'Right View', 'Left View'};
            app.ViewListBox.ValueChangedFcn = createCallbackFcn(app, @ViewListBoxValueChanged, true);
            app.ViewListBox.Value = 'Default View';
            app.SnapCameraButton = uibutton(app.UIFigure, 'push');
            app.SnapCameraButton.ButtonPushedFcn = createCallbackFcn(app, @SnapCameraButtonPushed, true);
            app.SnapCameraButton.Text = 'Snap Camera';
            app.NotinworkspaceLabel = uilabel(app.UIFigure);
            app.NotinworkspaceLabel.Text = {'Not in '; 'workspace'};
            app.NotinworkspaceLamp = uilamp(app.UIFigure);
            app.JointAnglesLabel = uilabel(app.UIFigure);
            app.JointAnglesLabel.Text = 'Joint Angles';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.CartesianXEditField = uieditfield(app.UIFigure, 'numeric');
            app.CartesianXEditField.ValueChangedFcn = createCallbackFcn(app, @CartesianXEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.CartesianYEditField = uieditfield(app.UIFigure, 'numeric');
            app.CartesianYEditField.ValueChangedFcn = createCallbackFcn(app, @CartesianYEditFieldValueChanged, true);
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.CartesianZEditField = uieditfield(app.UIFigure, 'numeric');
            app.CartesianZEditField.ValueChangedFcn = createCallbackFcn(app, @CartesianZEditFieldValueChanged, true);
            app.EndeffectorCartesianCoordinateLabel = uilabel(app.UIFigure);
            app.EndeffectorCartesianCoordinateLabel.Text = 'End-effector Cartesian Coordinate';
            app.InverseButton = uibutton(app.UIFigure, 'push');
            app.InverseButton.ButtonPushedFcn = createCallbackFcn(app, @InverseButtonPushed, true);
            app.InverseButton.Text = 'Inverse';
            app.ApproachObjectButton = uibutton(app.UIFigure, 'push');
            app.ApproachObjectButton.ButtonPushedFcn = createCallbackFcn(app, @ApproachObjectButtonPushed, true);
            app.ApproachObjectButton.Text = 'Approach Object';
            app.GrabCubeButton = uibutton(app.UIFigure, 'push');
            app.GrabCubeButton.ButtonPushedFcn = createCallbackFcn(app, @GrabCubeButtonPushed, true);
            app.GrabCubeButton.Text = 'Grab Cube';
            app.GrabCylinderButton = uibutton(app.UIFigure, 'push');
            app.GrabCylinderButton.ButtonPushedFcn = createCallbackFcn(app, @GrabCylinderButtonPushed, true);
            app.GrabCylinderButton.Text = 'Grab Cylinder';
            app.CubeGrabbedCheckBox = uicheckbox(app.UIFigure);
            app.CubeGrabbedCheckBox.ValueChangedFcn = createCallbackFcn(app, @CubeGrabbedCheckBoxValueChanged, true);
            app.CubeGrabbedCheckBox.Text = 'Cube Grabbed?';
            app.CylinderGrabbedCheckBox = uicheckbox(app.UIFigure);
            app.CylinderGrabbedCheckBox.ValueChangedFcn = createCallbackFcn(app, @CylinderGrabbedCheckBoxValueChanged, true);
            app.CylinderGrabbedCheckBox.Text = 'Cylinder Grabbed?';
            app.FwdTrajectoryButton = uibutton(app.UIFigure, 'push');
            app.FwdTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @FwdTrajectoryButtonPushed, true);
            app.FwdTrajectoryButton.Text = 'Fwd Trajectory';
            app.InvTrajectoryButton = uibutton(app.UIFigure, 'push');
            app.InvTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @InvTrajectoryButtonPushed, true);
            app.InvTrajectoryButton.Text = 'Inv Trajectory';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+10';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '+10';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '+10';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '-10';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '-10';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '-10';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '+10';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '+10';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '+10';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '-10';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '-10';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '-10';
            app.ControlPanelLabel = uilabel(app.UIFigure);
            app.ControlPanelLabel.Text = 'Control Panel';
            app.ApproachCubeButton = uibutton(app.UIFigure, 'push');
            app.ApproachCubeButton.ButtonPushedFcn = createCallbackFcn(app, @ApproachCubeButtonPushed, true);
            app.ApproachCubeButton.Text = 'Approach Cube';
            app.ApproachCylinderButton = uibutton(app.UIFigure, 'push');
            app.ApproachCylinderButton.ButtonPushedFcn = createCallbackFcn(app, @ApproachCylinderButtonPushed, true);
            app.ApproachCylinderButton.Text = 'Approach Cylinder';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'User View')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Camera View (Snapshot Before Pickup)')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Virtual_Robot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end